classdef DIP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        ChooseImageButton  matlab.ui.control.Button
        CropButton         matlab.ui.control.Button
        ExtractTextButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function ShowButtonPushed(app, event)


        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.jpg;*.png;*.tiff;*.jpeg;*.gif;*.tif');
            image=imread(cat(2,pathname,filename));
            imshow(image, 'parent', app.UIAxes);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)

           
            %faceDetector = vision.CascadeObjectDetector;
            %shapeInserter = vision.ShapeInserter('BorderColor','Custom','CustomBorderColor',[0 255 255]);
            %bboxes = step(faceDetector, face);
            
            %I_faces = step(shapeInserter,face,int32(bboxes));
            J = imcrop(image);
            imshow(J,'parent', app.UIAxes2);
            
        end

        % Button pushed function: ExtractTextButton
        function ExtractTextButtonPushed(app, event)
            
            ocrResults = ocr(image);
            bboxes = locateText(ocrResults, 'is', 'IgnoreCase', true);
            Iocr = insertShape(image, 'FilledRectangle', bboxes);
            imshow(Iocr,'Parent',app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {''; ''};
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Cropped Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ExtractTextButton = uibutton(app.UIFigure, 'push');
            app.ExtractTextButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractTextButtonPushed, true);
            app.ExtractTextButton.Text = 'Extract Text';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DIP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end